classdef RocketApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        UnitsMenu                      matlab.ui.container.Menu
        MainLayout                     matlab.ui.container.GridLayout
        MainTabGroup                   matlab.ui.container.TabGroup
        InputsTab                      matlab.ui.container.Tab
        InputsGridLayout               matlab.ui.container.GridLayout
        StartAnalysisButton            matlab.ui.control.Button
        DefinitionsPanel               matlab.ui.container.Panel
        EngineDefinitionGridLayout     matlab.ui.container.GridLayout
        InjectionLabel                 matlab.ui.control.Label
        InjectionPressureEditFieldWithUnit  EditFieldWithUnit
        InjectionTemperatureEditFieldWithUnit  EditFieldWithUnit
        ThrustEditFieldWithUnit        EditFieldWithUnit
        ChamberLabel                   matlab.ui.control.Label
        ContractionRatioEditFieldWithUnit  EditFieldWithUnit
        MaxChamberTemperatureEditFieldWithUnit  EditFieldWithUnit
        MaxThroatTemperatureEditFieldWithUnit  EditFieldWithUnit
        HeatPowerEditFieldWithUnit     EditFieldWithUnit
        NozzleLabel                    matlab.ui.control.Label
        ExpansionRatioEditFieldWithUnit  EditFieldWithUnit
        SeparationOffsetEditFieldWithUnit  EditFieldWithUnit
        AtmosphereLabel                matlab.ui.control.Label
        AtmosphericPressureEditFieldWithUnit  EditFieldWithUnit
        AtmosphericTemperatureEditFieldWithUnit  EditFieldWithUnit
        ProblemDefinitionPanel         matlab.ui.container.Panel
        ProblemDefinitionGridLayout    matlab.ui.container.GridLayout
        FractiontypeDropDown           matlab.ui.control.DropDown
        FractiontypeDropDownLabel      matlab.ui.control.Label
        PropellantsourceDropDown       matlab.ui.control.DropDown
        PropellantsourceDropDownLabel  matlab.ui.control.Label
        NumberofiterationsEditField    matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        ProblemsettingsLabel           matlab.ui.control.Label
        ProblemtypeButtonGroup         matlab.ui.container.ButtonGroup
        EngineAnalysisButton           matlab.ui.control.RadioButton
        FindOptimalNozzleButton        matlab.ui.control.RadioButton
        FindOptimalPropellantButton    matlab.ui.control.RadioButton
        PropellantsPanel               matlab.ui.container.Panel
        PropellantsGridLayout          matlab.ui.container.GridLayout
        PropellantsEditableTable       EditableTable
        PropellantSpeciesPanel         matlab.ui.container.Panel
        PropellantSpeciesGridLayout    matlab.ui.container.GridLayout
        SpeciesEditableTable           EditableTable
        OutputsTab                     matlab.ui.container.Tab
        OutputsGridLayout              matlab.ui.container.GridLayout
        OutputsTabGroup                matlab.ui.container.TabGroup
        SummaryTab                     matlab.ui.container.Tab
        SummaryGridLayout              matlab.ui.container.GridLayout
        PerformanceCharacteristicsPanel  matlab.ui.container.Panel
        PerformanceCharacteristicsGridLayout  matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        GraphingTab                    matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        DropDown2                      matlab.ui.control.DropDown
        DropDown2Label                 matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        rangeableElementArray;
        unitArray;
        propellantSourceArray;
        propellantSource;
        propellantArray;
        speciesNameArray;
        speciesRatioArray;
        mass_mole
        gas;
        nIterations;
        solverInputs;
        problemType;
    end
    
    methods (Access = private)
        function updateunits(app)
            import Unit.*
            
            for editField = app.rangeableElementArray
                editField.updateunits(app.unitArray);
            end
        end

        function setinputs(comp)
            comp.solverInputs.nIterations = comp.nIterations;
            comp.solverInputs.propellantArray = comp.prop;
        end

        function EditFieldRangeSwitched(app, event)
            for rangeableElement = app.rangeableElementArray
                rangeableElement.disablerange;
            end
        end

        function importunits(app)
            import Unit.*

            folders = strtrim(string(ls('Units')));
            folders = ("Units\" + folders(3 : end))';
            for folder = folders
                files = strtrim(string(ls(folder)));
                files = (folder + "\" + files(3 : end))';
                i = 1;
                for file = files
                    unit = Unit(file);
                    unitTypes().tostr(unit.gettype);
                    app.unitArray.(unitTypes().tostr(unit.gettype))(i) = unit;
                    i = i + 1;
                end
            end
            updateunits(app);
        end
        
        function setuprangeableelementarray(app)
            app.rangeableElementArray = EditFieldWithUnit.empty;
            app.rangeableElementArray(end + 1) = app.InjectionPressureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.InjectionTemperatureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.ContractionRatioEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;
            
            app.rangeableElementArray(end + 1) = app.MaxChamberTemperatureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.MaxThroatTemperatureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.HeatPowerEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.ExpansionRatioEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.SeparationOffsetEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.AtmosphericPressureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.AtmosphericTemperatureEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.ThrustEditFieldWithUnit;
            app.rangeableElementArray(end).reSetupFcn;

            app.rangeableElementArray(end + 1) = app.PropellantsEditableTable;
            
            for editField = app.rangeableElementArray
                %editField.updateunits(app.unitArray);
                editField.rangeswitchedFcn = createCallbackFcn(app, @EditFieldRangeSwitched, true);
            end

        end

        function setuptables(app)
            app.SpeciesEditableTable.updateunits(app.unitArray);
            app.SpeciesEditableTable.resetup;
            app.PropellantsEditableTable.updateunits(app.unitArray);
            app.PropellantsEditableTable.nameWhitelist = "propellant";
            app.PropellantsEditableTable.resetup;
        end

        function sources = updatepropellantSourceArray(app)
            sources = strtrim(string(ls('Phases')));
            sources = sources(3 : end)';
            app.propellantSourceArray = sources;
            app.PropellantsourceDropDown.Items = sources;
            app.PropellantsourceDropDown.ValueIndex = 1;
            app.PropellantsourceDropDownValueChanged;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            importunits(app);
            setuprangeableelementarray(app);
            updateunits(app);
            updatepropellantSourceArray(app);
            setuptables(app);
        end

        % Button pushed function: StartAnalysisButton
        function run(app, event)
            solverInputs.atmo = app.atmo;
            solverInputs.propellantArray = app.propellantArray;
            solverInputs.ratios = ratios;
            solverInputs.contractionRatio = contractionRatio;
            solverInputs.expansionRatio = expansionRatio;
            solverInputs.thrust = thrust;
            solverInputs.heatMass = heatMass;
            solverInputs.temperature_chamber_max = temperature_chamber_max;
            solverInputs.temperature_throat_max = temperature_throat_max;
            solverInputs.separationTolerance = separationTolerance;
            solverInputs.mass_mole = mass_mole;
        end

        % Value changed function: PropellantsourceDropDown
        function PropellantsourceDropDownValueChanged(app, event)
            app.propellantSource = app.propellantSourceArray(app.PropellantsourceDropDown.ValueIndex);
            app.gas = Gas(app.propellantSource);
            app.SpeciesEditableTable.nameWhitelist = app.gas.speciesNames;
            app.SpeciesEditableTable.clean;
        end

        % Callback function: PropellantsEditableTable
        function PropellantsEditableTableselectionchanged(app, event)
            selection = app.PropellantsEditableTable.selection;
            selection_old = app.PropellantsEditableTable.selection_old;

            if ~isempty(selection_old)
                app.PropellantsEditableTable.setdata(selection_old, app.SpeciesEditableTable.save);
            end
            if ~isempty(selection)
                if ~isempty(app.PropellantsEditableTable.getdata(selection))
                    app.SpeciesEditableTable.load(app.PropellantsEditableTable.getdata(selection));
                else
                    app.SpeciesEditableTable.clean;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.UnitsMenu = uimenu(app.UIFigure);
            app.UnitsMenu.Text = 'Units';
            app.MainLayout = uigridlayout(app.UIFigure);
            app.MainTabGroup = uitabgroup(app.MainLayout);
            app.InputsTab = uitab(app.MainTabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputsGridLayout = uigridlayout(app.InputsTab);
            app.PropellantSpeciesPanel = uipanel(app.InputsGridLayout);
            app.PropellantSpeciesPanel.Title = 'Selected propellant species';
            app.PropellantSpeciesGridLayout = uigridlayout(app.PropellantSpeciesPanel);
            app.SpeciesEditableTable = EditableTable(app.PropellantSpeciesGridLayout);
            app.PropellantsPanel = uipanel(app.InputsGridLayout);
            app.PropellantsPanel.Title = 'Propellants';
            app.PropellantsGridLayout = uigridlayout(app.PropellantsPanel);
            app.PropellantsEditableTable = EditableTable(app.PropellantsGridLayout);
            app.PropellantsEditableTable.canBeRanged = true;
            app.PropellantsEditableTable.hasNameWhitelist = false;
            app.PropellantsEditableTable.selectionchangedFcn = createCallbackFcn(app, @PropellantsEditableTableselectionchanged, true);
            app.ProblemDefinitionPanel = uipanel(app.InputsGridLayout);
            app.ProblemDefinitionPanel.Title = 'Problem definition';
            app.ProblemDefinitionGridLayout = uigridlayout(app.ProblemDefinitionPanel);
            app.ProblemtypeButtonGroup = uibuttongroup(app.ProblemDefinitionGridLayout);
            app.ProblemtypeButtonGroup.Title = 'Problem type';
            app.FindOptimalPropellantButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.FindOptimalPropellantButton.Text = 'Find Optimal Propellant';
            app.FindOptimalPropellantButton.Value = true;
            app.FindOptimalNozzleButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.FindOptimalNozzleButton.Text = 'Find Optimal Nozzle';
            app.EngineAnalysisButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.EngineAnalysisButton.Text = 'Engine Analysis';
            app.ProblemsettingsLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.ProblemsettingsLabel.Text = 'Problem settings';
            app.NumberofiterationsEditFieldLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.ProblemDefinitionGridLayout, 'numeric');
            app.NumberofiterationsEditField.Limits = [2 100];
            app.NumberofiterationsEditField.Value = 2;
            app.PropellantsourceDropDownLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.PropellantsourceDropDownLabel.Text = 'Propellant source';
            app.PropellantsourceDropDown = uidropdown(app.ProblemDefinitionGridLayout);
            app.PropellantsourceDropDown.Items = {'R1highT', 'gri30', 'air'};
            app.PropellantsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @PropellantsourceDropDownValueChanged, true);
            app.PropellantsourceDropDown.Value = 'R1highT';
            app.FractiontypeDropDownLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.FractiontypeDropDownLabel.Text = 'Fraction type';
            app.FractiontypeDropDown = uidropdown(app.ProblemDefinitionGridLayout);
            app.FractiontypeDropDown.Items = {'Mass', 'Mole'};
            app.FractiontypeDropDown.Value = 'Mass';
            app.DefinitionsPanel = uipanel(app.InputsGridLayout);
            app.DefinitionsPanel.Title = 'Definitions';
            app.EngineDefinitionGridLayout = uigridlayout(app.DefinitionsPanel);
            app.AtmosphericTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.AtmosphericTemperatureEditFieldWithUnit.name = "Atmospheric Temperature";
            app.AtmosphericTemperatureEditFieldWithUnit.unitType = 6;
            app.AtmosphericTemperatureEditFieldWithUnit.value = [288.15 273.15];
            app.AtmosphericPressureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.AtmosphericPressureEditFieldWithUnit.name = "Atmospheric Pressure";
            app.AtmosphericPressureEditFieldWithUnit.unitType = 5;
            app.AtmosphericPressureEditFieldWithUnit.value = [101325 0];
            app.AtmosphereLabel = uilabel(app.EngineDefinitionGridLayout);
            app.AtmosphereLabel.Text = 'Atmosphere';
            app.SeparationOffsetEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.SeparationOffsetEditFieldWithUnit.defaultUnitIndex = 2;
            app.SeparationOffsetEditFieldWithUnit.name = "Separation Offset";
            app.SeparationOffsetEditFieldWithUnit.unitType = 9;
            app.SeparationOffsetEditFieldWithUnit.value = [0 0.1];
            app.ExpansionRatioEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.ExpansionRatioEditFieldWithUnit.defaultUnitIndex = 4;
            app.ExpansionRatioEditFieldWithUnit.name = "Expansion Ratio";
            app.ExpansionRatioEditFieldWithUnit.unitType = 9;
            app.ExpansionRatioEditFieldWithUnit.value = [20 40];
            app.NozzleLabel = uilabel(app.EngineDefinitionGridLayout);
            app.NozzleLabel.Text = 'Nozzle';
            app.HeatPowerEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.HeatPowerEditFieldWithUnit.defaultUnitIndex = [4 2];
            app.HeatPowerEditFieldWithUnit.dimension = [1 -1];
            app.HeatPowerEditFieldWithUnit.name = "Heat Supplied";
            app.HeatPowerEditFieldWithUnit.unitType = [7 2];
            app.HeatPowerEditFieldWithUnit.value = [0 1000];
            app.MaxThroatTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.MaxThroatTemperatureEditFieldWithUnit.defaultUnitIndex = 3;
            app.MaxThroatTemperatureEditFieldWithUnit.name = "Max Throat Temperature";
            app.MaxThroatTemperatureEditFieldWithUnit.unitType = 6;
            app.MaxThroatTemperatureEditFieldWithUnit.value = [2000 3000];
            app.MaxChamberTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.MaxChamberTemperatureEditFieldWithUnit.defaultUnitIndex = 3;
            app.MaxChamberTemperatureEditFieldWithUnit.name = "Max Chamber Temperature";
            app.MaxChamberTemperatureEditFieldWithUnit.unitType = 6;
            app.MaxChamberTemperatureEditFieldWithUnit.value = [2000 3000];
            app.ContractionRatioEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.ContractionRatioEditFieldWithUnit.defaultUnitIndex = 4;
            app.ContractionRatioEditFieldWithUnit.name = "Contraction Ratio";
            app.ContractionRatioEditFieldWithUnit.unitType = 9;
            app.ContractionRatioEditFieldWithUnit.value = [10 15];
            app.ChamberLabel = uilabel(app.EngineDefinitionGridLayout);
            app.ChamberLabel.Text = 'Chamber';
            app.ThrustEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.ThrustEditFieldWithUnit.name = "Thrust";
            app.ThrustEditFieldWithUnit.unitType = 4;
            app.ThrustEditFieldWithUnit.value = [500 1000];
            app.InjectionTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.InjectionTemperatureEditFieldWithUnit.defaultUnitIndex = 3;
            app.InjectionTemperatureEditFieldWithUnit.name = "Injection Temperature";
            app.InjectionTemperatureEditFieldWithUnit.unitType = 6;
            app.InjectionTemperatureEditFieldWithUnit.value = [100 150];
            app.InjectionPressureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.InjectionPressureEditFieldWithUnit.defaultUnitIndex = 2;
            app.InjectionPressureEditFieldWithUnit.name = "Injection Pressure";
            app.InjectionPressureEditFieldWithUnit.unitType = 5;
            app.InjectionPressureEditFieldWithUnit.value = [1000000 2000000];
            app.InjectionLabel = uilabel(app.EngineDefinitionGridLayout);
            app.InjectionLabel.Text = 'Injection';
            app.StartAnalysisButton = uibutton(app.InputsGridLayout, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.StartAnalysisButton.Text = {'Start'; 'Analysis'};
            app.OutputsTab = uitab(app.MainTabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputsGridLayout = uigridlayout(app.OutputsTab);
            app.OutputsTabGroup = uitabgroup(app.OutputsGridLayout);
            app.SummaryTab = uitab(app.OutputsTabGroup);
            app.SummaryTab.Title = 'Summary';
            app.SummaryGridLayout = uigridlayout(app.SummaryTab);
            app.UITable = uitable(app.SummaryGridLayout);
            app.UITable.ColumnName = {'Injection'; 'Chamber'; 'Throat'; 'Exit'};
            app.UITable.RowName = {};
            app.PerformanceCharacteristicsPanel = uipanel(app.SummaryGridLayout);
            app.PerformanceCharacteristicsPanel.Title = 'Panel';
            app.PerformanceCharacteristicsGridLayout = uigridlayout(app.PerformanceCharacteristicsPanel);
            app.GraphingTab = uitab(app.OutputsTabGroup);
            app.GraphingTab.Title = 'Graphing';
            app.GridLayout = uigridlayout(app.GraphingTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDownLabel = uilabel(app.GridLayout);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown2Label = uilabel(app.GridLayout);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RocketApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end