classdef EditFieldWithUnit < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        RangedCheckBox  matlab.ui.control.CheckBox
        Hyphen          matlab.ui.control.Label
        EditFieldLabel  matlab.ui.control.Label
        EditField_1     matlab.ui.control.NumericEditField
        EditField_2     matlab.ui.control.NumericEditField
        UnitDropDown    matlab.ui.control.DropDown
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        rangeswitched
    end

    properties (Access = private)
        disableRangeCounter (1,1) double {mustBeInteger(disableRangeCounter)} = 0; % used to automatically disable range after one other edit field in an app turns range on.
        unit Unit = Unit('Units\Length\meter.unit');
        unitArray Unit = Unit('Units\Length\meter.unit');
    end
    
    properties (Access = public)
        dimension (1,1) double {mustBeInteger(dimension)} = 1;
        isRanged (1,1) logical = 0;
        name (1,1) string = "Edit Field";
        unitType string = "length";
        value (1,2) double = [0,1];
    end
    
    methods (Access = public)
        function updateunits(comp, unitArray, dimension, index)
            if nargin == 3
                comp.dimension = dimension;
            end
            comp.unitArray = unitArray;
            symbolArray = string.empty;
            for unit = unitArray
                symbolArray(end + 1) = convertCharsToStrings(unit.getsymbol(comp.dimension));
            end
            comp.UnitDropDown.Items = symbolArray;
            if nargin == 4
            comp.UnitDropDown.ValueIndex = index
            end
            comp.unit = comp.unitArray(comp.UnitDropDown.ValueIndex);
        end
        
        function disableRange(comp)
            if comp.disableRangeCounter == 1
                comp.disableRangeCounter = 0;
                comp.isRanged = false;
            elseif comp.disableRangeCounter == 2
                comp.disableRangeCounter = 1;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            import Enum.*
            import Unit.*
            comp.update;
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(comp, event)
            comp.unit = comp.unitArray(comp.UnitDropDown.ValueIndex);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(comp, event)
            comp.value(2) = comp.unit.remove(comp.EditField_2.Value, comp.dimension);
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(comp, event)
            comp.value(1) = comp.unit.remove(comp.EditField_1.Value, comp.dimension);
        end

        % Value changed function: RangedCheckBox
        function RangedCheckBoxValueChanged(comp, event)
            comp.isRanged = comp.RangedCheckBox.Value;
            if comp.isRanged == true
                comp.disableRangeCounter = 2;
                notify(comp, 'rangeswitched');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            import Unit.*

            comp.EditField_1.Value = comp.unit.apply(comp.value(1), comp.dimension);
            comp.EditField_2.Value = comp.unit.apply(comp.value(2), comp.dimension);
            comp.EditFieldLabel.Text = comp.name;
            if comp.isRanged == false
                comp.Hyphen.Enable = false;
                comp.EditField_2.Enable = false;
                comp.RangedCheckBox.Value = false;
            else
                comp.Hyphen.Enable = true;
                comp.EditField_2.Enable = true;
                comp.RangedCheckBox.Value = true;
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 22];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UnitDropDown = uidropdown(comp.GridLayout);
            comp.UnitDropDown.Items = {'unit 1', 'unit 2', 'unit 3'};
            comp.UnitDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @UnitDropDownValueChanged, true);
            comp.UnitDropDown.Value = 'unit 1';
            comp.EditField_2 = uieditfield(comp.GridLayout, 'numeric');
            comp.EditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_2ValueChanged, true);
            comp.EditField_1 = uieditfield(comp.GridLayout, 'numeric');
            comp.EditField_1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_1ValueChanged, true);
            comp.EditFieldLabel = uilabel(comp.GridLayout);
            comp.EditFieldLabel.Text = 'Edit Field';
            comp.Hyphen = uilabel(comp.GridLayout);
            comp.Hyphen.Text = 'to';
            comp.RangedCheckBox = uicheckbox(comp.GridLayout);
            comp.RangedCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RangedCheckBoxValueChanged, true);
            comp.RangedCheckBox.Text = 'Ranged?';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end