classdef EditableTable < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        AddButton       matlab.ui.control.Button
        RemoveButton    matlab.ui.control.Button
        MoveUpButton    matlab.ui.control.Button
        MoveDownButton  matlab.ui.control.Button
        UITable         matlab.ui.control.Table
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        addbuttonpressed
        changesapplied
        rangedisabled
        rangeswitched
        selectionchanged
    end

    properties (Access = private)
        columnValues double {mustBeInteger(columnValues)} = [1,2,4,6];
        disableRangeCounter (1,1) double {mustBeInteger(disableRangeCounter)} = 0; % used to automatically disable range after one other edit field in an app turns range on.
        disabledStyle (1,1)
        enabledStyle (1,1)
        fraction2Column double
        fractionColumn double
        fractionStyle (1,1)
        nRows (1,1) double = 0;
        rangedColumn logical
        symbolArray
        symbolColumn
        unitArray
        unitColumn Unit
        unitIndexColumn double {mustBeInteger(unitIndexColumn)}
    end
    
    properties (Access = public)
        canBeRanged logical = true;
        dataColumn cell
        hasNameWhitelist logical = true;
        isRanged (1,1) logical = 0;
        nameColumn
        nameWhitelist string = "propellant"; % if whitelist isn't active, this acts as a default name
        propertyName (1,1) string
        rangedIndex (1,1) double {mustBeInteger(rangedIndex)} = 0;
        ratio2Column double
        ratioColumn double
        selection double {mustBeInteger(selection)} = [];
        selection_old double {mustBeInteger(selection_old)} = [];
    end
    
    methods (Access = public)
        
        function unitType = unitType(comp)
            unitType = 9;
        end
        
        function enable(comp)
            comp.MoveUpButton.Enable = true;
            comp.MoveDownButton.Enable = true;
            comp.AddButton.Enable = true;
            comp.RemoveButton.Enable = true;
        end
        
        function disable(comp)
            comp.MoveUpButton.Enable = false;
            comp.MoveDownButton.Enable = false;
            comp.AddButton.Enable = false;
            comp.RemoveButton.Enable = false;
        end
        
        function resetup(comp)
            comp.postSetupFcn;
        end

        function setdata(comp, rows, newData)
            updatedatacolumn(comp, rows, newData);
        end

        function data = getdata(comp, rows)
            data = comp.dataColumn{rows};
        end

        function load(comp, input)
            if class(input.nameColumn) == "categorical"
                comp.nameColumn = input.nameColumn;
                comp.ratioColumn = input.ratioColumn;
                comp.ratio2Column = input.ratio2Column;
                comp.symbolColumn = input.symbolColumn;
                comp.unitColumn = input.unitColumn;
                comp.dataColumn = input.dataColumn;
                comp.unitIndexColumn = input.unitIndexColumn;
                comp.nRows = size(comp.nameColumn, 2);
                comp.updatetabledisplay;
            else
                comp.nRows = input.nSpecies;
                for i = 1 : comp.nRows
                    comp.addrow(input.nameColumn(i), input.ratioColumn(i), "%", double.empty);
                end
            end
        end

        function output = save(comp)
            output.nameColumn = comp.nameColumn;
            output.ratioColumn = comp.ratioColumn;
            output.ratio2Column = comp.ratio2Column;
            output.symbolColumn = comp.symbolColumn;
            output.unitColumn = comp.unitColumn;
            output.dataColumn = comp.dataColumn;
            output.unitIndexColumn = comp.unitIndexColumn;
        end

        function clean(comp)
            comp.selection = [];
            comp.UITable.Selection = [];

            if comp.hasNameWhitelist
                comp.nameColumn = categorical.empty;
            else
                comp.nameColumn = string.empty;
            end
            comp.ratioColumn = [];
            comp.unitColumn = Unit.empty;
            comp.symbolColumn = [];
            comp.unitIndexColumn = [];
            comp.dataColumn = cell.empty;
            if comp.canBeRanged
                comp.ratio2Column = [];
                comp.rangedIndex = 0;
            end

            comp.nRows = 0;

            comp.updatetabledisplay;
        end

        function testfun(comp)
            rows = comp.nRows;
            name = comp.nameColumn;
            ratio = comp.ratioColumn;
            ratio2 = comp.ratio2Column;
            fraction = comp.fractionColumn;
            fraction2 = comp.fraction2Column;
            data = comp.dataColumn;
            unit = comp.unitColumn;
            symbol = comp.symbolColumn;
            rangedIndex = comp.rangedIndex;
            selection = comp.selection;
            selection_old = comp.selection_old;
        end

        function teststart(comp)
            import Unit.*

            folders = strtrim(string(ls('Units')));
            folders = ("Units\" + folders(3 : end))';
            for folder = folders
                files = strtrim(string(ls(folder)));
                files = (folder + "\" + files(3 : end))';
                i = 1;
                for file = files
                    unit = Unit(file);
                    unitTypes().tostr(unit.gettype);
                    unitArray.(unitTypes().tostr(unit.gettype))(i) = unit;
                    i = i + 1;
                end
            end
            updateunits(comp, unitArray);
        end

        function addrow(comp, newName, newRatio, newSymbol, newData)
            comp.nRows = (size(comp.ratioColumn, 2) + 1);

            comp.updatenamecolumn(comp.nRows, newName);
            comp.updateratiocolumn(comp.nRows, newRatio(1));
            comp.updatesymbolcolumn(comp.nRows, newSymbol);
            comp.updatedatacolumn(comp.nRows, newData);
            if comp.canBeRanged
                comp.updateratio2column(comp.nRows, newRatio(2));
            end

            comp.updatetabledisplay;
        end

        function removerow(comp)
            rows = comp.UITable.Selection;
            if ~isempty(rows)
                comp.nameColumn(rows) = [];
                comp.ratioColumn(rows) = [];
                comp.unitColumn(rows) = [];
                comp.symbolColumn(rows) = [];
                comp.unitIndexColumn(rows) = [];
                comp.dataColumn(rows) = [];

                if comp.canBeRanged
                    comp.ratio2Column(rows) = [];
                    if ismember(comp.rangedIndex, rows)
                        comp.rangedCheckboxClicked(rows, false)
                    end
                end
                if isempty(comp.nameColumn)
                    if comp.hasNameWhitelist
                        comp.nameColumn = categorical.empty;
                    else
                        comp.nameColumn = string.empty;
                    end
                end

                comp.nRows = (size(comp.ratioColumn, 2));
                comp.selection = [];
                comp.selectionChanged;
                comp.updatetabledisplay;
            end
        end

        function moveuprow(comp)
            selected = comp.UITable.Selection;
            if ~isempty(selected) && min(selected) > 1

                for i = min(selected) - 1 : 1 : max(selected)
                    if ismember(i, selected)
                        comp.nameColumn([i-1, i]) = comp.nameColumn([i, i-1]);
                        comp.ratioColumn([i-1, i]) = comp.ratioColumn([i, i-1]);
                        comp.unitColumn([i-1, i]) = comp.unitColumn([i, i-1]);
                        comp.symbolColumn([i-1, i]) = comp.symbolColumn([i, i-1]);
                        comp.unitIndexColumn([i-1, i]) = comp.unitIndexColumn([i, i-1]);
                        comp.dataColumn([i-1, i]) = comp.dataColumn([i, i-1]);
                        if comp.canBeRanged
                            comp.ratio2Column([i-1, i]) = comp.ratio2Column([i, i-1]);
                            if comp.rangedIndex == i
                                comp.rangedIndex = comp.rangedIndex - 1;
                            elseif comp.rangedIndex == i - 1
                                comp.rangedIndex = comp.rangedIndex + 1;
                            end
                        end
                    end
                end
                comp.shiftselection(-1);
                updatetabledisplay(comp);
            end
        end

        function movedownrow(comp)
            selected = comp.UITable.Selection;
            if ~isempty(selected) && max(selected) < comp.nRows
                for i = max(selected) + 1 : - 1 : min(selected)
                    if ismember(i, selected)
                        comp.nameColumn([i+1, i]) = comp.nameColumn([i, i+1]);
                        comp.ratioColumn([i+1, i]) = comp.ratioColumn([i, i+1]);
                        comp.unitColumn([i+1, i]) = comp.unitColumn([i, i+1]);
                        comp.symbolColumn([i+1, i]) = comp.symbolColumn([i, i+1]);
                        comp.unitIndexColumn([i+1, i]) = comp.unitIndexColumn([i, i+1]);
                        comp.dataColumn([i+1, i]) = comp.dataColumn([i, i+1]);
                        if comp.canBeRanged
                            comp.ratio2Column([i+1, i]) = comp.ratio2Column([i, i+1]);
                            if comp.rangedIndex == i
                                comp.rangedIndex = comp.rangedIndex + 1;
                            elseif comp.rangedIndex == i + 1
                                comp.rangedIndex = comp.rangedIndex - 1;
                            end
                        end
                    end
                end
                comp.shiftselection(1);
                updatetabledisplay(comp);
            end
        end

        function updateunits(comp, unitArray)
            unitArray = unitArray.ratio;
            comp.unitArray = unitArray;
            comp.symbolArray = string.empty;
            for unit = unitArray
                comp.symbolArray(end + 1) = convertCharsToStrings(unit.getsymbol);
            end
        end

        function disablerange(comp)
            if comp.disableRangeCounter == 1
                comp.disableRangeCounter = 0;
                comp.rangedIndex = 0;
                comp.isRanged = false;
            elseif comp.disableRangeCounter == 2
                comp.disableRangeCounter = 1;
            end
            comp.updatetabledisplay;
        end

        function enablerange(comp, row)
            comp.UITable.Data(row, 4) = {true};
            comp.rangedCheckboxClicked(row, true);
        end
        
        function updateselection(comp)
            comp.selectionChanged;
        end
    end

    methods (Access = private)

        function updatenamecolumn(comp, rows, newName)

            newName = string(newName);

            if comp.hasNameWhitelist
                index = find(comp.nameWhitelist == newName, 1);
                if isempty(index)
                    index = 1;
                end
                if isempty(comp.nameColumn)
                    comp.nameColumn = categorical.empty;
                    comp.nameColumn = setcats(comp.nameColumn, comp.nameWhitelist);
                end
                comp.nameColumn(rows) = comp.nameWhitelist(index);
            else
                if isempty(comp.nameColumn)
                    comp.nameColumn = string.empty;
                end
                if strlength(newName) > 0
                    comp.nameColumn(rows) = newName;
                else
                    comp.nameColumn(rows) = comp.nameWhitelist(1) + comp.nRows;
                end
            end
        end

        function updateratiocolumn(comp, rows, newRatio)
            if isnumeric(newRatio)
                comp.ratioColumn(rows) = newRatio;
            end
        end

        function updateratio2column(comp, rows, newRatio)
            if isnumeric(newRatio)
                comp.ratio2Column(rows) = newRatio;
            end
        end

        function updatefractioncolumn(comp)
            comp.fractionColumn = [];
            comp.fraction2Column = [];
            denominator1 = sum(comp.ratioColumn);
            for i = 1 : comp.nRows
                comp.fractionColumn(i) = comp.unitColumn(i).apply(comp.ratioColumn(i) / denominator1);
            end
            if comp.rangedIndex ~= 0
                denominator2 = sum(comp.ratioColumn) - comp.ratioColumn(comp.rangedIndex) + comp.ratio2Column(comp.rangedIndex);
                for i = 1 : comp.nRows
                    if i == comp.rangedIndex
                        comp.fraction2Column(i) = comp.unitColumn(i).apply(comp.ratio2Column(i) / denominator2);
                    else
                        comp.fraction2Column(i) = comp.unitColumn(i).apply(comp.ratioColumn(i) / denominator2);
                    end
                end
            elseif comp.canBeRanged
                comp.fraction2Column = comp.fractionColumn;
            end
        end

        function updatesymbolcolumn(comp, rows, newSymbol)
            newSymbol = string(newSymbol);
            index = find(comp.symbolArray == newSymbol, 1);
            if isempty(index)
                index = 1;
            end
            comp.unitIndexColumn(rows) = index;
            unit = comp.unitArray(index);
            comp.unitColumn(rows) = unit;

            if isempty(comp.symbolColumn)
                comp.symbolColumn = categorical.empty;
                comp.symbolColumn = setcats(comp.symbolColumn, comp.symbolArray);
            end
            comp.symbolColumn(rows) = comp.symbolArray(index);
        end

        function updaterangedcolumn(comp)
            comp.rangedColumn = zeros(1, comp.nRows);
            if comp.rangedIndex ~= 0
                comp.rangedColumn(comp.rangedIndex) = true;
            end
        end
        
        function updatedatacolumn(comp, rows, newData)
            comp.dataColumn{rows} = newData;
        end

        function updatetabledisplay(comp)
            if isempty(comp.nameColumn)
                if comp.hasNameWhitelist
                    comp.nameColumn = categorical.empty;
                else
                    comp.nameColumn = string.empty;
                end
            end
            comp.updatefractioncolumn;
            comp.updaterangedcolumn;
            if comp.canBeRanged
                comp.UITable.Data = table(comp.nameColumn', comp.ratioColumn', comp.ratio2Column', ...
                    comp.fractionColumn', comp.fraction2Column', comp.symbolColumn', comp.rangedColumn');
                if comp.rangedIndex ~= 0
                    addStyle(comp.UITable, comp.enabledStyle, 'column', 3);
                    addStyle(comp.UITable, comp.fractionStyle, 'column', 5);
                    addStyle(comp.UITable, comp.disabledStyle, 'cell', [(1 : comp.nRows)', 3 * ones(comp.nRows, 1)]);
                    addStyle(comp.UITable, comp.enabledStyle, 'cell', [comp.rangedIndex, 3]);
                else
                    addStyle(comp.UITable, comp.disabledStyle, 'column', 3);
                    addStyle(comp.UITable, comp.disabledStyle, 'column', 5);
                end
            else
                comp.UITable.Data = table(comp.nameColumn', comp.ratioColumn', comp.fractionColumn', comp.symbolColumn');
            end
        end

        function rangedCheckboxClicked(comp, rows, isRanged)
            if isRanged == true
                comp.disableRangeCounter = 2;
                comp.rangedIndex = min(rows);
                comp.isRanged = true;
                notify(comp, 'rangeswitched');
            else
                comp.rangedIndex = 0;
                comp.isRanged = false;
                notify(comp, 'rangedisabled');
            end
        end

        function shiftselection(comp, shift)
            comp.UITable.Selection = comp.UITable.Selection + shift;
            comp.selection = comp.UITable.Selection;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.disabledStyle = uistyle("BackgroundColor", [0.9, 0.9, 0.9], "FontColor", [0.75, 0.75, 0.75]);
            comp.enabledStyle = uistyle("BackgroundColor", [1, 1, 1], "FontColor", "black");
            comp.fractionStyle = uistyle("BackgroundColor", [0.95, 0.95, 0.95], "FontColor", "black");
            
            if comp.canBeRanged
                comp.UITable.ColumnName = ["Name", "Ratio from", "to", "Fraction from", "to", "Unit", "Ranged?"];
                comp.UITable.ColumnWidth = {"1x", "1x", "1x", "1x", "1x", 60, 70};
                comp.UITable.ColumnEditable = [true, true, true, false, false, true, true];
                comp.columnValues = [1,2,3,4,5,6,7];
                addStyle(comp.UITable, comp.disabledStyle, 'column', [3, 5]);
                addStyle(comp.UITable, comp.fractionStyle, 'column', 4);
                addStyle(comp.UITable, comp.enabledStyle, 'column', [1, 2, 6, 7]);
            else
                comp.UITable.ColumnName = ["Name", "Ratio", "Fraction", "Unit"];
                comp.UITable.ColumnWidth = {"1x", "1x", "1x", 60};
                comp.UITable.ColumnEditable = [true, true, false, true];
                comp.columnValues = [1,2,4,6];
                addStyle(comp.UITable, comp.fractionStyle, 'column', 3);
                addStyle(comp.UITable, comp.enabledStyle, 'column', [1, 2, 4]);
            end

            if comp.hasNameWhitelist
                comp.nameColumn = categorical.empty;
            else
                comp.nameColumn = string.empty;
            end
            comp.UITable.Selection = [];
            % comp.nameWhitelist = ["H2", "H2O", "O2", "H2", "N2", "NH3"];
            % comp.teststart;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(comp, event)
            column = comp.columnValues(event.Indices(2));
            row = event.Indices(1);

            if column == 1
                comp.updatenamecolumn(row, event.NewData);
            elseif column == 2
                comp.updateratiocolumn(row, event.NewData);
            elseif column == 3
                comp.updateratio2column(row, event.NewData);
            elseif column == 4
            elseif column == 5
            elseif column == 6
                comp.updatesymbolcolumn(row, event.NewData);
            elseif column == 7
                comp.rangedCheckboxClicked(row, event.NewData);
            end
            comp.updatetabledisplay;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(comp, event)
            if comp.hasNameWhitelist
                newName = comp.nameWhitelist(1);
            else
                newName = comp.nameWhitelist(1) + " " + (comp.nRows + 1);
            end

            if isempty(comp.ratioColumn)
                newRatio = 1;
            else
                newRatio = sum(comp.ratioColumn) / (comp.nRows);
            end
            if comp.canBeRanged
                newRatio(2) = newRatio;
            end

            newSymbol = "%";
            newData = double.empty;

            addrow(comp, newName, newRatio, newSymbol, newData);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(comp, event)
            removerow(comp);
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(comp, event)
            moveuprow(comp);
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(comp, event)
            movedownrow(comp);
        end

        % Selection changed function: UITable
        function selectionChanged(comp, event)
            if comp.UITable.Selection > comp.nRows
                comp.UITable.Selection = [];
            end
            comp.selection_old = comp.selection;
            comp.selection = comp.UITable.Selection;
            notify(comp, 'selectionchanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 587 542];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'Name'; 'Ratio'; 'Fraction'; 'Unit'};
            comp.UITable.RowName = {};
            comp.UITable.SelectionType = 'row';
            comp.UITable.ColumnEditable = [true true false false];
            comp.UITable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @UITableCellEdit, true);
            comp.UITable.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @selectionChanged, true);
            comp.MoveDownButton = uibutton(comp.GridLayout, 'push');
            comp.MoveDownButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MoveDownButtonPushed, true);
            comp.MoveDownButton.Text = '▼';
            comp.MoveUpButton = uibutton(comp.GridLayout, 'push');
            comp.MoveUpButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MoveUpButtonPushed, true);
            comp.MoveUpButton.Text = '▲';
            comp.RemoveButton = uibutton(comp.GridLayout, 'push');
            comp.RemoveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RemoveButtonPushed, true);
            comp.RemoveButton.Text = '−';
            comp.AddButton = uibutton(comp.GridLayout, 'push');
            comp.AddButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AddButtonPushed, true);
            comp.AddButton.Text = '+';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end